classdef mainWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Easy_Assessment   matlab.ui.Figure
        b_calculate       matlab.ui.control.Button
        t_lim_prec        matlab.ui.control.NumericEditField
        t_lim_prec_label  matlab.ui.control.Label
        b_savePath        matlab.ui.control.Button
        Label             matlab.ui.control.Label
        logPanel          matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        defPath_pub
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.logPanel.Value = 'Запуск программы';
            app.logPanel.Value = [app.logPanel.Value; 'Путь по умолчанию...'];
            config_id = fopen([pwd, '\bin\.cfg'], 'r');
            app.defPath_pub = textscan(config_id, '%s');
            fclose(config_id);
            if length(app.defPath_pub) < 1
                app.logPanel.Value = [app.logPanel.Value, ' не задан'];
            else
            end
        end

        % Close request function: Easy_Assessment
        function Easy_AssessmentCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: b_calculate
        function b_calculateButtonPushed(app, event)
            d = uiprogressdlg(app.Easy_Assessment,'Title','Пожалуйста подождите',...
                'Message','Загрузка...','Indeterminate','on');
            [file,path] = uigetfile('*.xlsx');
            if length(file) > 1
                fPath = [path,file];
                % =============================== night ===================================
                % =========================================================================
                [~,~,fact_t_n] = xlsread(fPath, 1,'C5:C67');
                [~,~,forecast_t_n] = xlsread(fPath, 1,'D5:D67');
                t_night = temperature(fact_t_n, forecast_t_n);
                
                [~,~,fact_p_n] = xlsread(fPath, 1,'F5:F67');
                [~,~,forecast_p_n] = xlsread(fPath, 1,'G5:G67');
                p_night = precipitation(fact_p_n, forecast_p_n, fact_t_n, app.t_lim_prec.Value);
                
                %==================== phenomenons =========================================
                [~,~,forecast_wind_n] = xlsread(fPath, 1,'I5:I67');
                [~,~,fact_wind_n] = xlsread(fPath, 1,'U5:U67');
                wind_night = wind_func(fact_wind_n, forecast_wind_n);
                
                [~,~,phen_fore_n] = xlsread(fPath, 1,'J5:T67');
                [~,~,phen_fact_n] = xlsread(fPath, 1,'V5:AF67');
                assessment_night = phen_assessment(wind_night, phen_fore_n, phen_fact_n);
                
                % =============================== day =====================================
                % =========================================================================
                
                [~,~,fact_t_d] = xlsread(fPath, 1,'AJ5:AJ67');
                [~,~,forecast_t_d] = xlsread(fPath, 1,'AK5:AK67');
                t_day = temperature(fact_t_d, forecast_t_d);
                
                [~,~,fact_p_d] = xlsread(fPath, 1,'AM5:AM67');
                [~,~,forecast_p_d] = xlsread(fPath, 1,'AN5:AN67');
                p_day = precipitation(fact_p_d, forecast_p_d, fact_t_d, app.t_lim_prec.Value);
                
                %==================== phenomenons =========================================
                [~,~,fact_wind_d] = xlsread(fPath, 1,'AP5:AP67');
                [~,~,forecast_wind_d] = xlsread(fPath, 1,'BB5:BB67');
                wind_day = wind_func(fact_wind_d, forecast_wind_d);
                
                [~,~,phen_fore_d] = xlsread(fPath, 1,'AQ5:BA67');
                [~,~,phen_fact_d] = xlsread(fPath, 1,'BC5:BM67');
                assessment_day = phen_assessment(wind_day, phen_fore_d, phen_fact_d);
                
                % ============== night_output =============================================
                % =========================================================================
                out_dir = uigetdir();
                if length(out_dir) < 1
                    xlswrite([pwd, '/output_table.xlsx'], fact_t_n, 1, 'C5:C67');
                    xlswrite([pwd, '/output_table.xlsx'], forecast_t_n, 1, 'D5:D67');
                    xlswrite([pwd, '/output_table.xlsx'], t_night, 1, 'E5:E67');
                    
                    xlswrite([pwd, '/output_table.xlsx'], fact_p_n, 1, 'F5:F67');
                    xlswrite([pwd, '/output_table.xlsx'], forecast_p_n, 1, 'G5:G67');
                    xlswrite([pwd, '/output_table.xlsx'], p_night, 1, 'H5:H67');
                    
                    % =============== phenomenon assessment night ==============
                    xlswrite([pwd, '/output_table.xlsx'], fact_wind_n, 1, 'I5:I67');
                    xlswrite([pwd, '/output_table.xlsx'], forecast_wind_n, 1, 'U5:U67');
                    xlswrite([pwd, '/output_table.xlsx'], phen_fact_n, 1, 'J5:T67');
                    xlswrite([pwd, '/output_table.xlsx'], phen_fore_n, 1, 'V5:AF67');
                    xlswrite([pwd, '/output_table.xlsx'], assessment_night, 1, 'AG5:AG67');
                    
                    % ============= day_output ================================================
                    % =========================================================================
                    
                    xlswrite([pwd, '/output_table.xlsx'], fact_t_d, 2, 'C5:C67');
                    xlswrite([pwd, '/output_table.xlsx'], forecast_t_d, 2, 'D5:D67');
                    xlswrite([pwd, '/output_table.xlsx'], t_day, 2, 'E5:E67');
                    
                    xlswrite([pwd, '/output_table.xlsx'], fact_p_d, 2, 'F5:F67');
                    xlswrite([pwd, '/output_table.xlsx'], forecast_p_d, 2, 'G5:G67');
                    xlswrite([pwd, '/output_table.xlsx'], p_day, 2, 'H5:H67');
                    
                    % =============== phenomenon assessment day ==============
                    xlswrite([pwd, '/output_table.xlsx'], fact_wind_d, 2, 'I5:I67');
                    xlswrite([pwd, '/output_table.xlsx'], forecast_wind_d, 2, 'U5:U67');
                    xlswrite([pwd, '/output_table.xlsx'], phen_fact_d, 2, 'J5:T67');
                    xlswrite([pwd, '/output_table.xlsx'], phen_fore_d, 2, 'V5:AF67');
                    xlswrite([pwd, '/output_table.xlsx'], assessment_day, 2, 'AG5:AG67');
                    d.close();
                else
                    uialert(app.Easy_Assessment, 'Ошибка при сохранении файла', 'Ошибка', 'Icon','error');
                    d.close();
                end
            else
                uialert(app.Easy_Assessment, 'Ошибка при загрузке файла', 'Ошибка', 'Icon','error');
                d.close();
            end
        end

        % Value changed function: t_lim_prec
        function t_lim_precValueChanged(app, event)
            
        end

        % Button pushed function: b_savePath
        function b_savePathButtonPushed(app, event)
            d = uiprogressdlg(app.Easy_Assessment,...
                'Message','Ожидание пути.','Indeterminate','on');
            dir = uigetdir();
            user_conf_id = fopen([pwd, '\bin\.cfg'], 'w');
            fprintf(user_conf_id, '%s' , dir);
            fclose(user_conf_id);
            uialert(app.Easy_Assessment, 'Путь по умолчанию для выходного файли изменен', 'Информация', 'Icon','info');
            d.Close();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Easy_Assessment = uifigure;
            app.Easy_Assessment.Name = 'Easy assessment';
            app.Easy_Assessment.CloseRequestFcn = createCallbackFcn(app, @Easy_AssessmentCloseRequest, true);
            app.b_calculate = uibutton(app.Easy_Assessment, 'push');
            app.b_calculate.ButtonPushedFcn = createCallbackFcn(app, @b_calculateButtonPushed, true);
            app.b_calculate.Text = 'Рассчитать';
            app.t_lim_prec = uieditfield(app.Easy_Assessment, 'numeric');
            app.t_lim_prec.Limits = [-50 50];
            app.t_lim_prec.ValueChangedFcn = createCallbackFcn(app, @t_lim_precValueChanged, true);
            app.t_lim_prec.Value = -5;
            app.t_lim_prec_label = uilabel(app.Easy_Assessment);
            app.t_lim_prec_label.Text = {'Пороговая t° '; 'для осадков'};
            app.b_savePath = uibutton(app.Easy_Assessment, 'push');
            app.b_savePath.ButtonPushedFcn = createCallbackFcn(app, @b_savePathButtonPushed, true);
            app.b_savePath.Text = {'Задать/изменить '; 'путь по умолчанию'};
            app.logPanel = uitextarea(app.Easy_Assessment);
            app.logPanel.Editable = 'off';
            app.Label = uilabel(app.Easy_Assessment);
            app.Label.Text = 'Журнал работы программы';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainWindow

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Easy_Assessment)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Easy_Assessment)
        end
    end
end